classdef ControlPanel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        ContolPanel        matlab.ui.container.Panel
        StartButton        matlab.ui.control.Button
        StopButton         matlab.ui.control.Button
        LampLabel          matlab.ui.control.Label
        Lamp               matlab.ui.control.Lamp
        ContinueButton     matlab.ui.control.Button
        StepForwardButton  matlab.ui.control.Button
        PauseButton        matlab.ui.control.Button
        GridSwitchLabel    matlab.ui.control.Label
        GridSwitch         matlab.ui.control.ToggleSwitch
        Image              matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            trigger = webread('http://localhost/remote/start.php');
 
            if (trigger == "start")
                set_param('testingV10','SimulationCommand','start');
                app.Lamp.Color = 'g';
            end
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            trigger = webread('http://localhost/remote/stop.php');
    
            if (trigger == "stop")
                set_param('testingV10','SimulationCommand','stop');
                app.Lamp.Color = 'r';
            end
           
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            trigger = webread('http://localhost/remote/pause.php');
 
            if (trigger == "pause")
                set_param('testingV10','SimulationCommand','pause');
                app.Lamp.Color = 'y';
            end
            
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            set_param('testingV10','SimulationCommand','continue');
            app.Lamp.Color = 'g';
        end

        % Button pushed function: StepForwardButton
        function StepForwardButtonPushed(app, event)
            trigger = webread('http://localhost/remote/next.php');
 
            if (trigger == "next")
                 set_param('testingV10','SimulationCommand','stepForward');
            end

        end

        % Callback function
        function SwitchValueChanged(app, event)
            value = app.GridSwitch.Value;
            if strcmp(value, 'Off')
                set_param('testingV10/Automatic Transfer Switch/ATS','Value','0');
            else
                set_param('testingV10/Automatic Transfer Switch/ATS','Value','1');
            end
        end

        % Callback function
        function offButtonPushed(app, event)
            set_param('testingV10/Automatic Transfer Switch/Constant3','Value','0');
        end

        % Callback function
        function onButtonPushed(app, event)
            set_param('testingV10/Automatic Transfer Switch/Constant3','Value','1');
        end

        % Value changed function: GridSwitch
        function GridSwitchValueChanged2(app, event)
            value = app.GridSwitch.Value;
            if strcmp(value, 'Off')
                set_param('testingV10/Automatic Transfer Switch/Constant3','Value','0');
            else
                set_param('testingV10/Automatic Transfer Switch/Constant3','Value','1');
            end            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ContolPanel = uipanel(app.UIFigure);
            app.ContolPanel.Title = 'Contol Panel';
            app.StartButton = uibutton(app.ContolPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.ContolPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.LampLabel = uilabel(app.ContolPanel);
            app.LampLabel.Text = 'Lamp';
            app.Lamp = uilamp(app.ContolPanel);
            app.ContinueButton = uibutton(app.ContolPanel, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            app.StepForwardButton = uibutton(app.ContolPanel, 'push');
            app.StepForwardButton.ButtonPushedFcn = createCallbackFcn(app, @StepForwardButtonPushed, true);
            app.StepForwardButton.Text = 'Step Forward';
            app.PauseButton = uibutton(app.ContolPanel, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.GridSwitchLabel = uilabel(app.ContolPanel);
            app.GridSwitchLabel.Text = 'Grid Switch';
            app.GridSwitch = uiswitch(app.ContolPanel, 'toggle');
            app.GridSwitch.ValueChangedFcn = createCallbackFcn(app, @GridSwitchValueChanged2, true);
            app.Image = uiimage(app.ContolPanel);
            app.Image.ImageSource = '168-1687245_wireless-signal-icon-image-vector-wifi-signal.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ControlPanel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end